classdef scriptedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptsUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ImplantTab                      matlab.ui.container.Tab
        ScriptsEnabledSwitchLabel       matlab.ui.control.Label
        ScriptsEnabledSwitch            matlab.ui.control.Switch
        ActiveTable                     matlab.ui.control.Table
        CheckBox                        matlab.ui.control.CheckBox
        FlashTable                      matlab.ui.control.Table
        RefreshFlashButton              matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        ScriptExecutionPeriodEditFieldLabel  matlab.ui.control.Label
        ScriptExecutionPeriodEditField  matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        RefreshPeriodButton             matlab.ui.control.Button
        PDOTimerTriggeredScriptsButton  matlab.ui.control.Button
        EventHistoryTextAreaLabel       matlab.ui.control.Label
        EventHistoryTextArea            matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ForceIDsButton                  matlab.ui.control.Button
        ScriptControlsPanel             matlab.ui.container.Panel
        StartScriptButton               matlab.ui.control.Button
        StopScriptButton                matlab.ui.control.Button
        RunScriptOnceButton             matlab.ui.control.Button
        RunScriptTimedButton            matlab.ui.control.Button
        CheckStatusButton               matlab.ui.control.Button
        DeleteScriptButton              matlab.ui.control.Button
        ScriptLibraryTab                matlab.ui.container.Tab
        DownloadButton                  matlab.ui.control.Button
        AsssembleButton                 matlab.ui.control.Button
        LibraryTable                    matlab.ui.control.Table
        EditButton                      matlab.ui.control.Button
        AddExistingButton               matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        AddNewButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        nnp = []; % Description
        scripts = [];
        activeRow = [];
        scriptsFile = [];
    end
    
    methods (Access = private)
        function populateLibraryTable(app)
            nScripts = length(app.scripts.library);
            
            data = cell(0,7);
            row=0;
            for i=1:nScripts
                
                if ~isempty(app.scripts.library{i})
                    row = row+1;
                    
                    data{row,1}=i;
                    sp = find(cellfun(@(x) x.id == i, app.scripts.active));
                    if length(sp)>1
                        error('more than one script shares script pointer')
                    elseif length(sp)==1
                        data{row,2}=sp;
                    else
                        data{row,2} = 0;
                    end
                               
                    
                    data{row,3}=app.scripts.library{i}.name;
                    data{row,4}=app.scripts.library{i}.path;
                    data{row,5}=length(app.scripts.library{i}.downloadFile);
                    if ~isempty(app.scripts.library{i}.downloadFile)
                        idDownload = app.scripts.library{i}.downloadFile(end);
                    else
                        idDownload = [];
                    end
                    %indicate if Script ID in download doesn't match specified ID
                    if ~isempty(idDownload) && idDownload ~= i
                        data{row,6}=['ID mismatch: ' num2str(idDownload) '!'];
                    else
                        data{row,6}=app.scripts.library{i}.assembleDate;
                    end
                    data{row,7}=app.scripts.library{i}.downloadDate;
                    
                    list = dir(app.scripts.library{i}.path);
                    if isempty(list)
                        data{row,8}='Could not find file';
                    else
                        data{row,8}=list.date;
                    end
                end
            end
            
            app.LibraryTable.Data = data;
            app.LibraryTable.ColumnFormat = {'numeric', 'numeric','char','char'}; %right align
        end
    
        function populateFlashTable(app)
            %Takes ~6minutes to read entire script space using readMemory, so instead just read length and ID from each
            startAddress = hex2dec('30000');
            scriptSize = 2048;
            scriptSizeLong = 7680;
            
            data = cell(25,2);
            bgColor = repmat([.7, .7, .7],25,1);
            
            app.FlashTable.ColumnFormat = {'numeric', 'numeric'}; %right align
            for i=1:25
                addr = startAddress + (i-1)*scriptSize;
                resp = readmem(app.nnp, 7, 'flash', addr, 2, false);
                if length(resp)==2
                    scriptLen = double(typecast(resp, 'uint16'));
                    
                    if (i<25 && scriptLen<scriptSize) || scriptLen < scriptSizeLong
                        resp = app.nnp.readMemory(7, 'flash', addr+scriptLen-1, 1,false);
                        if length(resp) == 1
                            scriptID = double(resp);
                            bgColor(i,:) = [1 1 1];
                        else
                            scriptID = '?';
                            bgColor(i,:) = [1 1 0];
                        end
                    elseif scriptLen == 65535
                        scriptLen = '-';
                        scriptID = '-';
                        bgColor(i,:) = [.95 .95 .95];
                    else
                        scriptLen = [num2str(scriptLen) '?'];
                        scriptID = '?';
                        bgColor(i,:) = [1 0 0];
                    end
                else
                    scriptLen = '?';
                    scriptID = '?';
                    bgColor(i,:) = [1 0.5 0];
                end
                data{i,1} = scriptID;
                data{i,2} = scriptLen;
                app.FlashTable.Data = data;
                app.FlashTable.BackgroundColor = bgColor;
            end
        end
    
        function populateActiveTable(app)
            %Can only read 50 bytes at a time so no more than 12 script controls
            resp = app.nnp.read(7, '1f51', 1,12, 'uint32'); %read script 1-12 controls
            if length(resp) ~= 12
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptControl(1:12) = double(resp);
            end
            resp = app.nnp.read(7, '1f51', 13,12, 'uint32'); %read script 13-24 controls
            if length(resp) ~= 12    
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptControl(13:24) = double(resp);
            end
            resp =  app.nnp.read(7, '1f51', 25,1, 'uint32'); %read script 25 controls
            if length(resp) ~= 1
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptControl(25) = double(resp);
            end
            
            resp = app.nnp.read(7, '1f56', 1, 'uint8'); %read script order
            if length(resp) ~= 25
                app.RefreshButton.BackgroundColor = [1 0 0];
                return
            else
                scriptOrder = double(resp);
            end
            app.RefreshButton.BackgroundColor = [.96 .96 .96];
            data = cell(25,7);
            % bit  0   : 1=run 
            % bit  1   : 1=run continuous, 0=halt
            % bit  2   : 1 = reinit global var table
            % bit  3-7 : reserved for future use
            % bit  8-15: script ID
            % bit 16-23: reserved for future use
            % bit 24-30: error code
            % bit 31   : 1= run once and successful execution
            
            % green: if run continuous (run = 2)
            % yellow: run once (run = 1)
            % red: error
            % white: stopped
            % gray: unused
            
            bgColor = zeros(25,3);
            for i=1:25
                scriptID = bitshift(bitand(scriptControl(i), hex2dec('FF00')), -8);
                run = bitand(scriptControl(i), hex2dec('03'));
                errorCode = bitshift(bitand(scriptControl(i), hex2dec('7F000000')), -24);
                runOnceResult = bitget(scriptControl(i),32);
                
                
                fprintf('\n#%1.0f run %1.0f, result %1.0f', i,run, runOnceResult);
                
                if scriptID == 0 
                    bgColor(i,:) = [.95 .95 .95];
                    data{i,1} = [];
                else
                    if errorCode
                        bgColor(i,:) = [1 0 0];
                    else
                        if run==2
                            bgColor(i,:) = [0 1 0];
                        elseif run == 1 && runOnceResult == 1
                            bgColor(i,:) = [1 1 0];
                        elseif run>0
                            bgColor(i,:) = [1 0.5 0];
                        else 
                            bgColor(i,:) = [1 1 1];
                        end
                    end
                    
                    if length(app.ActiveTable.Data)==25 && isequal(app.ActiveTable.Data{i,1}, true)
                        data{i,1} = true; %was already selected
                    else
                        data{i,1} = false;
                    end
                    data{i,5} = errorCode;
                    data{i,2} = scriptID;

                    if length(app.scripts.library)<scriptID || isempty(app.scripts.library{scriptID})
                        if app.scripts.active{i}.id == scriptID
                            data{i,3} = '(ScriptID not in Library)';
                        else
                            data{i,3} = ['(ScriptID mismatch, expected:' num2str(app.scripts.active{i}.id) ')'];
                        end
                    else
                        
                        data{i,3} = app.scripts.library{scriptID}.name;
                        sizeBytes = length(app.scripts.library{scriptID}.downloadFile);
                        if sizeBytes > 0
                            data{i,6} = sizeBytes;
                            data{i,7} = app.scripts.library{scriptID}.downloadDate;
                        else
                            data{i,6} = '?';
                            data{i,7} = '?';
                        end
                    end
                    
                end
                
                data{i,4} = scriptOrder(i);
            end
            %first column is logical or empty, use default formatting. use char and numeric to left or right alight data
            app.ActiveTable.ColumnFormat = {'', 'numeric','char','numeric','numeric', 'numeric', 'numeric'}; 
            app.ActiveTable.Data = data;
            app.ActiveTable.BackgroundColor = bgColor;
            
            %TODO:disable Script Controls if no scripts selected
        end
        
        function id = findNextScriptID(app)
            nScripts = length(app.scripts.library);
            for i=1:nScripts
                if isempty(app.scripts.library)
                    id = i;
                    return;
                end
            end
            id = i+1;
        end
        
        function trimScripts(app, id)
            app.scripts.library{id} =[];
            
            % if last id, remove it, not just make it empty
            while id == length( app.scripts.library)
                if isempty(app.scripts.library{id})
                    app.scripts.library(id) = [];
                    id = id - 1;
                    if id == 0
                        break;
                    end
                else 
                    break;
                end
            end
        end
        
        function sp = scriptsSelected(app)
            %return as row vector so can be used in for loop 
            sp = find(cellfun(@(x) ~isempty(x) && x==true, app.ActiveTable.Data(:,1)))';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            app.scripts.library = cell(2,1);
            app.scripts.library{1} = struct('name', 'trunkramp', 'path', 'C:\Users\Joris\Documents\Repositories\NNP\matlabapi\trunk\apps\Scripting\trunkramp.nnpscript', 'downloadFile', [], 'assembleDate', [],'downloadDate', [],'notes', []);
            app.scripts.library{2} = struct('name', 'demo', 'path', 'C:\Users\Joris\Documents\Repositories\NNP\matlabapi\trunk\apps\Scripting\demo.nnpscript', 'downloadFile', [], 'assembleDate', [],'downloadDate', [],'notes', []);
            app.scripts.active = cell(25,1);
            app.scripts.active{1} = struct('id', 1, 'order', 1);
            app.scripts.active{2} = struct('id', 2, 'order', 2);
            for j=3:25
                app.scripts.active{j} = struct('id', 0, 'order', 0);
            end
            app.populateActiveTable();
            app.FlashTable.Data = cell(25,2);
            app.FlashTable.BackgroundColor = [.7, .7, .7];
            app.populateLibraryTable();
            app.LibraryTableCellSelection([]);
            %app.populateFlashTable();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.populateActiveTable();
        end

        % Button pushed function: AsssembleButton
        function AsssembleButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            file = app.LibraryTable.Data{app.activeRow,4};
            id = app.LibraryTable.Data{app.activeRow,1};
            download = assembler(id, file);
            app.scripts.library{app.activeRow}.downloadFile = download;
            app.scripts.library{app.activeRow}.assembleDate = char(datetime);
            app.scripts.library{app.activeRow}.downloadDate = 'Reassembled!';
            
            app.populateLibraryTable();
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)

            checkCell = app.ActiveTable.Data(:,1);
            notempty = cellfun(@(x) ~isempty(x), checkCell);
            if all(cell2mat(checkCell(notempty)))
                app.CheckBox.Value = false;
                checkCell(notempty) = {false};
            else
                app.CheckBox.Value = true;
                checkCell(notempty) = {true};
            end
            
            app.ActiveTable.Data(:,1) = checkCell;
        end

        % Button pushed function: RefreshFlashButton
        function RefreshFlashButtonPushed(app, event)
            app.populateFlashTable();
        end

        % Cell edit callback: ActiveTable
        function ActiveTableCellEdit(app, event)
            col = event.Indices(2);
            row = event.Indices(1);
            newData = event.NewData;
            oldData = event.PreviousData;
            

            if col == 4
                if isnan(newData) || newData < 0 || newData > 25
                    app.ActiveTable.Data(row, col) = oldData;
                    return
                end
                scriptOrder = cell2mat(app.ActiveTable.Data(:,4))';
                if newData == 0 
                     scriptOrder(row) = 0;
                     app.ActiveTable.Data{row, col} = 0;
                else
                    scriptOrder = cell2mat(app.ActiveTable.Data(:,4))';
                    i = find(scriptOrder==newData);
                    i(i==row) = [];
                    if ~isempty(i) 
                        userResp = questdlg('Two scripts should not have same order value.  Do you want to swap order with other script, zero other script, or cancel?', 'Script Order', 'Swap', 'Zero', 'Cancel','Cancel');
                        switch userResp
                            case 'Swap'
                                scriptOrder(i) = oldData;
                                app.ActiveTable.Data{i, col} = oldData;
                            case 'Zero'
                                scriptOrder(i) = 0;
                                app.ActiveTable.Data{i, col} = 0;
                            otherwise
                                app.ActiveTable.Data{row, col} = oldData;
                                return
                        end
                    end
                end
                resp = app.nnp.write(7, '1f56', 1, scriptOrder, 'uint8'); %write script order
                if ~isequal(resp, 0)
                    msgbox('write script order may have failed, hit refresh table to read back')
                end
            end
            
            
        end

        % Cell selection callback: LibraryTable
        function LibraryTableCellSelection(app, event)
            if isempty(event)
                app.activeRow = [];
            else
                app.activeRow = event.Indices(1);
            end
            
            %highlight selected row in yellow.  Unassigned script pointer rows are gray, assigned are white
            scriptPs = cell2mat(app.LibraryTable.Data(:,2));
            bgColor=zeros(length(scriptPs),3);
            for i=1:length(scriptPs)
                if i == app.activeRow
                    if scriptPs(i)==0
                        bgColor(i,:)=[.95 .95 0];
                    else
                        bgColor(i,:)=[1 1 0];
                    end
                elseif scriptPs(i)==0
                    bgColor(i,:)=[.95 .95 .95];
                else
                    bgColor(i,:)=[1 1 1];
                end
            end
            app.LibraryTable.BackgroundColor = bgColor;
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            download = app.scripts.library{app.activeRow}.downloadFile;
            if length(download) < 10
                if isempty(download)
                    msgbox('assemble script first')
                else
                    msgbox('invalid short download')
                end
                return
            end
            
            %check if current row already has script pointer assigned, if so set as default
            sp = app.LibraryTable.Data{app.activeRow,2};
            scriptPs = cell2mat(app.LibraryTable.Data(:,2));
            if sp~=0
                default = sp;
            else
                default = [];
            end

            %build a List Dialog to allow user to choose script pointer
            listStr = cell(25,1);
            for i=1:25
                row = find(scriptPs==i);
                if isempty(row)
                    id = app.ActiveTable.Data{i,2};
                    if isempty(id) || id==0
                        nameStr = 'Available';
                        if isempty(default)
                            default = i; %set default to first available SP
                        end
                    else
                        if app.scripts.active{i}.id==0
                            %read back from module, but not expected (not in scripts.active)
                            nameStr = ['? (Unknown ScriptID: ' num2str(id) '!)']; 
                        else
                            %in scripts.active but not included in scripts.library
                            nameStr = ['? (Active ScriptID: ' num2str(id) '!)']; 
                        end
                    end
                else
                    nameStr = app.LibraryTable.Data{row,3};
                end
                listStr{i} = ['#' num2str(i) ':' nameStr];

            end
            userResp = listdlg('ListString', listStr, 'SelectionMode','single','InitialValue', default, ...
                'PromptString', 'Select Script download location:', 'OKString', 'Download'); 

            if ~isempty(userResp)
                sp = userResp;
                %TODO: 
                % confirm download if to already used location?
                % confirm assembly is current?
                
                result = loadscript(app.nnp, sp, download);
                if isequal(result, [1 0])
                    id = app.LibraryTable.Data{app.activeRow,1};
                    app.scripts.library{id}.downloadDate = char(datetime);
                    app.scripts.active{sp}.id=id;
                    app.populateActiveTable();
                else
                    msgbox(['error on script download: ' num2str(result)]);
                    app.scripts.library{id}.downloadDate = 'Error Downloading!';
                end
                app.populateLibraryTable();
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            winopen(app.LibraryTable.Data{app.activeRow,4})
        end

        % Button pushed function: AddExistingButton
        function AddExistingButtonPushed(app, event)
            [filename, pathname ] = uigetfile('*.nnpscript', 'Choose script(s) to add','Multiselect','on');

            %if single file selected, convert to cell array
            if ~iscell(filename) 
                if filename == 0 
                    return
                end
                filename{1} = filename;
            end
            nFiles = length(filename);
                
            %find next available script ID, and assign new path filename
            n=length('.nnpscript');
            for i=1:nFiles
                file = [pathname filename{i}];
                name = filename{i}(1:end-n);
                id = app.findNextScriptID();
                %Force column Cell array
                if length(app.scripts.library)==1 && id>1
                    app.scripts.library = [app.scripts.library; {[]}];
                end 
                app.scripts.library{id} = struct('name', name, 'path', file, 'downloadFile', [], 'assembleDate', [],'downloadDate', [],'notes', []);
            end
            %update table and active row (if multiple added, active will be last one added)
            app.populateLibraryTable();
            scriptIDs = cell2mat(app.LibraryTable.Data(:,1));
            row = find(scriptIDs==id); 
            app.LibraryTableCellSelection(struct('Indices', [row, 0] ));
        end

        % Button pushed function: AddNewButton
        function AddNewButtonPushed(app, event)
             [filename, pathname ] = uiputfile('*.nnpscript', 'Create new empty script file');
            if filename == 0 
                return
            else
                file = [pathname filename];
            end
            %find next available script ID, and assign new path filename
            n=length('.nnpscript');
            name = filename(1:end-n);
            id = app.findNextScriptID();
            %Force column Cell array
            if length(app.scripts.library)==1 && id>1
                app.scripts.library = [app.scripts.library; {[]}];
            end 
            app.scripts.library{id} = struct('name', name, 'path', file, 'downloadFile', [], 'assembleDate', [],'downloadDate', [],'notes', []);
            %create file and open for editing
            fclose(fopen(file, 'w'));
            winopen(file);
            %update table and active row
            app.populateLibraryTable();
            scriptIDs = cell2mat(app.LibraryTable.Data(:,1));
            row = find(scriptIDs==id);
            app.LibraryTableCellSelection(struct('Indices', [row, 0] ));
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            id = app.LibraryTable.Data{app.activeRow, 1};
            app.trimScripts(id);
            app.populateLibraryTable();
            app.LibraryTableCellSelection([]);
        end

        % Cell edit callback: LibraryTable
        function LibraryTableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            newData = event.NewData;
            oldData = event.PreviousData;
            
            switch col
                case 1 %ID
                    scriptIDs = cell2mat(app.LibraryTable.Data(:,1));
                    scriptIDs(row) = []; %ignore row being modified
                    if isnan(newData) || ismember(newData, scriptIDs) || newData<1 || newData>255
                        msgbox('script ID should be unique and in range 1-255')
                        app.LibraryTable.Data{row,col}=oldData;
                        return
                    else
                        if isempty(app.LibraryTable.Data{row,6})
                            userResp = 'Yes';
                        else
                            userResp = questdlg('Changing Script ID requires reassembling and downloading script.  Do you want to continue?');
                        end
                        if isequal(userResp, 'Yes')
                            app.scripts.library{newData}=app.scripts.library{oldData};
                            app.trimScripts(oldData);
                            %app.scripts.library{newData}.assembleDate = 'ID changed!';
                        else
                            app.LibraryTable.Data{row,col}=oldData;
                            return
                        end
                    end
%                 case 2 %script pointer
%                     scriptPs = cell2mat(app.LibraryTable.Data(:,2));
%                     scriptPs(row) = []; %ignore row being modified
%                     if isnan(newData) || ismember(newData, scriptPs) || newData<0 || newData>25
%                         msgbox('script pointer should be unassigned (0) or unique and in range 1-25')
%                         app.LibraryTable.Data{row,col}=oldData;
%                         return
%                     else
%                         if isempty(app.LibraryTable.Data{row,7})
%                             userResp = 'Yes';
%                         else
%                             userResp = questdlg('Changing Script Pointer requires redownloading script.  Do you want to continue?');
%                         end
%                         if isequal(userResp, 'Yes')
%                             id = app.LibraryTable.Data{row,1};
%                             app.scripts.library{id}.downloadDate = 'SP changed!';
%                             %TODO change other table
%                         else
%                             app.LibraryTable.Data{row,col}=oldData;
%                             return
%                         end
%                     end
                case 3 %name
                    app.scripts.library{row}.name = newData;
                otherwise
                    %not modifiable
                    return
            end
            
            %all changes to this point require table update
            app.populateLibraryTable();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %TODO: check if need to save previous
            [filename, pathname ] = uigetfile('*.mat', 'Open script parameters mat File');
            if filename == 0 
                return
            else
                app.scriptsFile = [pathname filename];
            end
            app.scripts = load(app.scriptsFile);
            if ~isfield(app.scripts, 'active') || ~isfield(app.scripts, 'library')
                msgbox('Scripts mat file must contain variables named "active" and "library"')
                if ~iscell(app.scripts.active) || ~isequal(size(app.scripts.active), [25,1])
                    msgbox('Scripts mat file must contain 25x1 cell array named "active" ');
                end
            end
            app.populateActiveTable();
            app.populateLibraryTable();
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.scriptsFile)
                SaveAsMenuSelected(app, event);
            else
                active = app.scripts.active;
                library = app.scripts.library;
                save(app.scriptsFile, 'active', 'library');
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname ] = uiputfile('*.mat', 'Save As script parameters mat File');
            if filename == 0 
                return
            else
                app.scriptsFile = [pathname filename];
                SaveMenuSelected(app, event);   
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.scripts.library = cell(0,1);
            app.scripts.active = cell(25,1);
            for j=1:25
                app.scripts.active{j} = struct('id', 0, 'order', 0);
            end
            app.populateActiveTable();
            app.populateLibraryTable();
        end

        % Button pushed function: ForceIDsButton
        function ForceIDsButtonPushed(app, event)
            for i = 1:25
                id = app.FlashTable.Data{i,1};
                if isscalar(id) && isnumeric(id)
                    %TODO: currently only pushes non zero IDs.  Should it also push zeros?
                    id = bitshift(uint32(id),8);
                    resp = app.nnp.write(7, '1f51', i, id, 'uint32');
                    if ~isequal(resp, 0)
                        disp('failed write - should retry')
                    end
                end
            end
            %TODO: should have an NMT save command here?
            app.populateActiveTable();
            app.populateLibraryTable();
        end

        % Button pushed function: DeleteScriptButton
        function DeleteScriptButtonPushed(app, event)
            
            for sp=app.scriptsSelected
                resp = app.nnp.nmt(7, hex2dec('90'), sp); %delete script NMT
                if ~isequal(resp, hex2dec('90'))
                    app.DeleteButton.BackgroundColor = [1 0 0];
                else
                    app.DeleteButton.BackgroundColor = [.96 .96 .96];
                end
                %TODO: repopulate individual rows of Flash Table here?
            end
            app.populateActiveTable();
        end

        % Button pushed function: StartScriptButton
        function StartScriptButtonPushed(app, event)
            for sp = app.scriptsSelected
                resp = app.nnp.nmt(7,hex2dec('8D'), sp); %Start Script
            end
            app.populateActiveTable();
        end

        % Button pushed function: StopScriptButton
        function StopScriptButtonPushed(app, event)
            for sp = app.scriptsSelected
                resp = app.nnp.nmt(7,hex2dec('AE'), sp); %Stop Script
            end
            app.populateActiveTable();
        end

        % Button pushed function: RunScriptOnceButton
        function RunScriptOnceButtonPushed(app, event)
            for sp = app.scriptsSelected 
                resp = app.nnp.nmt(7,hex2dec('B3'), sp); %Run Script Once
            end
            app.populateActiveTable();
        end

        % Button pushed function: CheckStatusButton
        function CheckStatusButtonPushed(app, event)
            msgbox('not yet implemented')
        end

        % Button pushed function: RunScriptTimedButton
        function RunScriptTimedButtonPushed(app, event)
            msgbox('not yet implemented')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptsUIFigure = uifigure('Visible', 'off');
            app.ScriptsUIFigure.Name = 'Scripts';
            app.FileMenu = uimenu(app.ScriptsUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.HelpMenu = uimenu(app.ScriptsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.ScriptsUIFigure);
            app.ImplantTab = uitab(app.TabGroup);
            app.ImplantTab.Title = 'Implant';
            app.ScriptsEnabledSwitchLabel = uilabel(app.ImplantTab);
            app.ScriptsEnabledSwitchLabel.Text = 'Scripts Enabled';
            app.ScriptsEnabledSwitch = uiswitch(app.ImplantTab, 'slider');
            app.ActiveTable = uitable(app.ImplantTab);
            app.ActiveTable.ColumnName = {'-'; 'ID'; 'Name'; 'Order'; 'Error'; 'Size'; 'Downloaded'};
            app.ActiveTable.ColumnEditable = [true false false true false false false];
            app.ActiveTable.CellEditCallback = createCallbackFcn(app, @ActiveTableCellEdit, true);
            app.ActiveTable.Tooltip = {'Reads from:'; '  1F51 (Script Controls) '; '  1F56 (Script Order)'; 'and references scripts from library that have matching script IDs. '; ' '; 'Use checkboxes to control which scripts are affected by the Script Control buttons.'; ''; 'Change Script Order by modifying values directly in table.  Zero indicates that the script is not executed by the Script Round Robin even if there is a valid scriptID.  '; ''; 'To change script IDs:'; '  use the Script Library tab'; ' and download scripts'; '    OR'; '  force scriptIDs to match values from script flash'};
            app.CheckBox = uicheckbox(app.ImplantTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.FlashTable = uitable(app.ImplantTab);
            app.FlashTable.ColumnName = {'ID'; 'Size'};
            app.FlashTable.Tooltip = {'Reads downloaded script lengths and IDs directly from PM script Flash.'};
            app.RefreshFlashButton = uibutton(app.ImplantTab, 'push');
            app.RefreshFlashButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFlashButtonPushed, true);
            app.RefreshFlashButton.Text = 'Refresh';
            app.RefreshButton = uibutton(app.ImplantTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ScriptExecutionPeriodEditFieldLabel = uilabel(app.ImplantTab);
            app.ScriptExecutionPeriodEditFieldLabel.Text = 'Script Execution Period';
            app.ScriptExecutionPeriodEditField = uieditfield(app.ImplantTab, 'numeric');
            app.ScriptExecutionPeriodEditField.Limits = [80 150];
            app.ScriptExecutionPeriodEditField.Tooltip = {'Writes to 2001.5 when changed.  '; ''; 'Reads from 2001.5 on refresh.'};
            app.ScriptExecutionPeriodEditField.Value = 100;
            app.msLabel = uilabel(app.ImplantTab);
            app.msLabel.Text = 'ms';
            app.RefreshPeriodButton = uibutton(app.ImplantTab, 'push');
            app.RefreshPeriodButton.Text = 'Refresh';
            app.PDOTimerTriggeredScriptsButton = uibutton(app.ImplantTab, 'push');
            app.PDOTimerTriggeredScriptsButton.Text = {'PDO/Timer '; 'Triggered'; 'Scripts'};
            app.EventHistoryTextAreaLabel = uilabel(app.ImplantTab);
            app.EventHistoryTextAreaLabel.Text = 'Event History';
            app.EventHistoryTextArea = uitextarea(app.ImplantTab);
            app.Label = uilabel(app.ImplantTab);
            app.Label.Text = '#';
            app.ForceIDsButton = uibutton(app.ImplantTab, 'push');
            app.ForceIDsButton.ButtonPushedFcn = createCallbackFcn(app, @ForceIDsButtonPushed, true);
            app.ForceIDsButton.Text = '←Force IDs';
            app.ScriptControlsPanel = uipanel(app.ImplantTab);
            app.ScriptControlsPanel.Title = 'Script Controls';
            app.StartScriptButton = uibutton(app.ScriptControlsPanel, 'push');
            app.StartScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptButtonPushed, true);
            app.StartScriptButton.Text = 'Start Script';
            app.StopScriptButton = uibutton(app.ScriptControlsPanel, 'push');
            app.StopScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StopScriptButtonPushed, true);
            app.StopScriptButton.Text = 'Stop Script';
            app.RunScriptOnceButton = uibutton(app.ScriptControlsPanel, 'push');
            app.RunScriptOnceButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptOnceButtonPushed, true);
            app.RunScriptOnceButton.Text = 'Run Script Once';
            app.RunScriptTimedButton = uibutton(app.ScriptControlsPanel, 'push');
            app.RunScriptTimedButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptTimedButtonPushed, true);
            app.RunScriptTimedButton.Text = 'Run Script Timed';
            app.CheckStatusButton = uibutton(app.ScriptControlsPanel, 'push');
            app.CheckStatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckStatusButtonPushed, true);
            app.CheckStatusButton.Text = 'Check Status';
            app.DeleteScriptButton = uibutton(app.ScriptControlsPanel, 'push');
            app.DeleteScriptButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteScriptButtonPushed, true);
            app.DeleteScriptButton.Text = 'Delete Script';
            app.ScriptLibraryTab = uitab(app.TabGroup);
            app.ScriptLibraryTab.Title = 'Script Library';
            app.DownloadButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.AsssembleButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AsssembleButton.ButtonPushedFcn = createCallbackFcn(app, @AsssembleButtonPushed, true);
            app.AsssembleButton.Text = 'Asssemble';
            app.LibraryTable = uitable(app.ScriptLibraryTab);
            app.LibraryTable.ColumnName = {'ID'; '#'; 'Name'; 'File'; 'Size'; 'Assembled'; 'Downloaded'; 'File Modified'};
            app.LibraryTable.RowName = {};
            app.LibraryTable.ColumnEditable = [true false true false false false false false];
            app.LibraryTable.CellEditCallback = createCallbackFcn(app, @LibraryTableCellEdit, true);
            app.LibraryTable.CellSelectionCallback = createCallbackFcn(app, @LibraryTableCellSelection, true);
            app.EditButton = uibutton(app.ScriptLibraryTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.AddExistingButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AddExistingButton.ButtonPushedFcn = createCallbackFcn(app, @AddExistingButtonPushed, true);
            app.AddExistingButton.Text = 'Add Existing';
            app.DeleteButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AddNewButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AddNewButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewButtonPushed, true);
            app.AddNewButton.Text = 'Add New';
            % Show the figure after all components are created
            app.ScriptsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptsUIFigure)
        end
    end
end