classdef channeledit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelsEditorFigure  matlab.ui.Figure
        UITable               matlab.ui.control.Table
        AddChannelButton      matlab.ui.control.Button
        RemoveChannelButton   matlab.ui.control.Button
        ApplyButton           matlab.ui.control.Button
        RevertButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        parentApp = [] 
        row = [];
        channels = [];
    end
    
    methods (Access = private)
        
        function populateTable(app)
            
            k=1;    
            for node=1:size(app.channels,1) 
                for ch=1:size(app.channels,2)
                    if isempty(app.channels{node,ch})
                        continue
                    end
                    d{k,1} = node;
                    d{k,2} = ch; 
                    d{k,3} = app.channels{node,ch}.name;
                    d{k,4} = app.channels{node,ch}.min;
                    d{k,5} = app.channels{node,ch}.max;
                    d{k,6} = app.channels{node,ch}.notes;
                    
                    k=k+1;
                end
            end
            app.UITable.Data = d;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PARENTAPP)
            app.parentApp = PARENTAPP;
            app.channels = app.parentApp.nnps.channels;
            app.populateTable();
            app.UITable.ColumnFormat = {'char', 'char'};
        end

        % Button pushed function: AddChannelButton
        function AddChannelButtonPushed(app, event)
           
            resp = inputdlg({'node', 'channel'});
            
            if length(resp)~=2
                return;
            end
            node = str2double(resp{1});
            ch = str2double(resp{2});
            
            
            if length(node)~=1 || isnan(node) || node<=0 || node>15 || ...
                length(ch)~=1  || isnan(ch)   || ch<1    || ch>4
                warning('invalid node/ch entry')
                return;
            end
            
            sz = size(app.channels);
            if sz(1)>=node && sz(2)>=ch && ~isempty(app.channels{node, ch})
                warning('node/ch already exists')
                return;
            end
            
            
            app.channels{node, ch} = struct('name', '?', 'min', 0, 'max', 255, 'notes', '');
            app.populateTable();
            
        end

        % Button pushed function: RemoveChannelButton
        function RemoveChannelButtonPushed(app, event)
            if isempty(app.row)  || app.row > size(app.UITable.Data, 1)
                return;
            end
            
            d = app.UITable.Data;
            node = d{app.row,1};
            ch = d{app.row, 2};
            
            app.channels{node, ch} = [];
            % if no channels for node, and last node, remove node entirely
            while node == size( app.channels, 1)
                if all(cellfun(@(x) isempty(x), app.channels(node,:)))
                    app.channels(node, :) = [];
                    node = node - 1;
                    if node == 0
                        break;
                    end
                else 
                    break;
                end
            end

            d(app.row,:) = [];
            app.UITable.Data = d;
        end

        % Close request function: ChannelsEditorFigure
        function ChannelsEditorFigureCloseRequest(app, event)
            if isvalid(app.parentApp)      
                if ~isequal(app.parentApp.nnps.channels, app.channels)
                    resp = questdlg('Do you want to apply changes?', 'Channel Editor');
                    if isequal(resp, 'Yes')
                        app.parentApp.nnps.channels = app.channels;
                    elseif ~isequal(resp, 'No')
                        return
                    end
                    
                end
            else
                warning('parent app closed')
            end
            delete(app)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isvalid(app.parentApp)     
                app.parentApp.nnps.channels = app.channels;
            else
                warning('parent app closed')
            end  
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.row = event.Indices(1);
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            if isvalid(app.parentApp)     
                app.channels = app.parentApp.nnps.channels;
                app.populateTable();
            else
                warning('parent app closed')
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelsEditorFigure = uifigure('Visible', 'off');
            app.ChannelsEditorFigure.Name = 'Channels Editor';
            app.ChannelsEditorFigure.CloseRequestFcn = createCallbackFcn(app, @ChannelsEditorFigureCloseRequest, true);
            app.UITable = uitable(app.ChannelsEditorFigure);
            app.UITable.ColumnName = {'Node'; 'Channel'; 'Muscle Name'; 'Min'; 'Max'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddChannelButton = uibutton(app.ChannelsEditorFigure, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannelButton.Text = '+';
            app.RemoveChannelButton = uibutton(app.ChannelsEditorFigure, 'push');
            app.RemoveChannelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelButtonPushed, true);
            app.RemoveChannelButton.Text = '-';
            app.ApplyButton = uibutton(app.ChannelsEditorFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RevertButton = uibutton(app.ChannelsEditorFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert ';
            % Show the figure after all components are created
            app.ChannelsEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channeledit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelsEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelsEditorFigure)
        end
    end
end