classdef nnpsurgerysuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurgerySuiteUIFigure  matlab.ui.Figure
        ConfigAPButton        matlab.ui.control.Button
        NetworkScannerButton  matlab.ui.control.Button
        ProfilerButton        matlab.ui.control.Button
        BTTestButton          matlab.ui.control.Button
        NNPAccessPointButton  matlab.ui.control.StateButton
        CommChecklistButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
         nnp = []; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NNPAccessPointButton.Value = 1;
            app.NNPAccessPointButtonValueChanged();
        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp);            
        end

        % Callback function
        function NNPAccessPointButtonPushed(app, event)
                        if ~isempty(app.nnp) 
                 delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value 
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                 while true
                    app.nnp = NNPAPI();
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            close(app.SurgerySuiteUIFigure);
                            return;
                        end
                    else
                        break;
                    end
                 end
                 app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
             end
            
        end

        % Button pushed function: BTTestButton
        function BTTestButtonPushed(app, event)
             bptest(app.nnp);
        end

        % Button pushed function: NetworkScannerButton
        function NetworkScannerButtonPushed(app, event)
             netscannerf(app.nnp);
        end

        % Close request function: SurgerySuiteUIFigure
        function SurgerySuiteUIFigureCloseRequest(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            delete(app)
        end

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
             if ~isempty(app.nnp) 
                 delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value 
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                 while true
                    app.nnp = NNPAPI();
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            close(app.NNPUtilitiesUIFigure);
                            return;
                        end
                    else
                        break;
                    end
                 end
                 app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
             end
        end

        % Button pushed function: CommChecklistButton
        function CommChecklistButtonPushed(app, event)
            commchecklist();
        end

        % Button pushed function: ProfilerButton
        function ProfilerButtonPushed(app, event)
            profiler(app.nnp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SurgerySuiteUIFigure = uifigure('Visible', 'off');
            app.SurgerySuiteUIFigure.Name = 'NNP Surgery Suite';
            app.SurgerySuiteUIFigure.CloseRequestFcn = createCallbackFcn(app, @SurgerySuiteUIFigureCloseRequest, true);
            app.ConfigAPButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Text = 'Radio Configuration';
            app.NetworkScannerButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.NetworkScannerButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkScannerButtonPushed, true);
            app.NetworkScannerButton.Text = {'Network'; 'Scanner'};
            app.ProfilerButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ProfilerButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilerButtonPushed, true);
            app.ProfilerButton.Text = 'Profiler';
            app.BTTestButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.BTTestButton.ButtonPushedFcn = createCallbackFcn(app, @BTTestButtonPushed, true);
            app.BTTestButton.Text = {'BP'; 'Test'};
            app.NNPAccessPointButton = uibutton(app.SurgerySuiteUIFigure, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            app.NNPAccessPointButton.Value = true;
            app.CommChecklistButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.CommChecklistButton.ButtonPushedFcn = createCallbackFcn(app, @CommChecklistButtonPushed, true);
            app.CommChecklistButton.Text = {'Communications'; 'Checklist'};
            % Show the figure after all components are created
            app.SurgerySuiteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnpsurgerysuite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurgerySuiteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurgerySuiteUIFigure)
        end
    end
end