classdef nodeedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NodeEditorFigure  matlab.ui.Figure
        UITable           matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            maxNodes = 15;
            nodes = parentApp.nnps.nodes;
            nodeModes = parentApp.nnp.getStatus();
            
            d = cell(maxNodes,4);
            rowColor = zeros(maxNodes, 3);
            for i=1:maxNodes
                
                d{i,1} = i; %node
                if length(nodes)< i || isempty(nodes{i})
                    d{i,2} = '-';
                    d{i,3} = '-';
                else
                    d{i,2} = nodes{i}.name;
                    d{i,3} = nodes{i}.type;
                end
                d{i,4} = nodeModes{i};
                if isequal(nodeModes{i}, 'Not Connected')
                    if length(nodes)< i || isempty(nodes{i})
                        rowColor(i,:) = [.94 .94 .94]; %gray: not present expected
                    else
                        rowColor(i,:) = [1 0 0]; %red: not present but should be
                    end
                else
                    if length(nodes)< i || isempty(nodes{i})
                        rowColor(i,:) = [1 1 0]; %yellow: present but not expected
                    else
                        rowColor(i,:) = [0 1 0]; %green: present and should be
                    end
                end
            end
            app.UITable.Data = d;
            app.UITable.ColumnFormat = {'char'};
            app.UITable.BackgroundColor = rowColor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NodeEditorFigure = uifigure('Visible', 'off');
            app.NodeEditorFigure.Name = 'Node Editor';
            app.UITable = uitable(app.NodeEditorFigure);
            app.UITable.ColumnName = {'Node'; 'Name'; 'Type'; 'Online Mode'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false true true false];
            % Show the figure after all components are created
            app.NodeEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nodeedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NodeEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NodeEditorFigure)
        end
    end
end