classdef patternAutoCreate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CancelButton              matlab.ui.control.Button
        KeepButton                matlab.ui.control.Button
        CreatePatternGroupButton  matlab.ui.control.Button
        TemplateDropDown          matlab.ui.control.DropDown
        TemplateDropDownLabel     matlab.ui.control.Label
        cmdIDEditField            matlab.ui.control.NumericEditField
        cmdIDEditFieldLabel       matlab.ui.control.Label
        GroupEditField            matlab.ui.control.NumericEditField
        GroupEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        newPatterns=[]; 
        templateStructure=[]; %fields: 'templateSize','templateCommandArray','templateNames','templateMuscleTypes','templateMatrix'
        profileTable=[];
        colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
                      'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
        saveFile=[];
        maxType=13;
        template=[]; % we could just use the template structure, but I chose such long names, this was easier...
        commandArray;
        commandLevels;
        createdPatterns=[];
        parentApp = [];
        keepOrCancel=false;
    end
    
    methods (Access = public)
        
        function typeIndex = getTypeIndex(app,mtype)
          tIndex=find(strcmp(mtype,app.templateStructure.templateMuscleTypes.MuscleType));
          if ~isempty(tIndex)
             typeIndex=tIndex; 
          else
             typeIndex=app.maxType; % other 
          end
        end

        function createPatterns(app)
        % for each row in the profile, calculate the associated pattern
        % structure, which consists of group, node, ch, cmdID, n, cmd, pw, amp
        % colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
        % 'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
            profileRows=size(app.profileTable,1); % we could do this without the table eventually
            app.newPatterns={}; % clear the array before starting.  Only needed if running a second time around
            patRow=0; % only create patterns for rows used in pattern group
            for profRow=1:profileRows
                mtype=app.profileTable{profRow,app.colNum.Type};
                tInd = app.getTypeIndex(mtype); % index into the template array, app.template
                if tInd>0&&tInd<app.maxType % if OTH or undefined, do not create pattern
                    % check whether used at all in this template
                    if sum(app.template(tInd,:))>0 % check whether used at all in this template
                        patRow=patRow+1; % new pattern row
                        app.newPatterns{patRow}.group=app.GroupEditField.Value; % could make this a property
                        app.newPatterns{patRow}.node=app.profileTable{profRow,app.colNum.Node};
                        app.newPatterns{patRow}.ch=app.profileTable{profRow,app.colNum.Ch};
                        app.newPatterns{patRow}.cmdID=app.cmdIDEditField.Value;
                        app.newPatterns{patRow}.n=app.commandLevels;
                        app.newPatterns{patRow}.cmd=app.commandArray;
                        templateRow=app.template(tInd,:); % use for calculations
                        pw=zeros(1,9);% start with all zeros
                        thPW=app.profileTable{profRow,app.colNum.ThPW};
                        maxPW=app.profileTable{profRow,app.colNum.MaxPW};
                        ramp=maxPW-thPW;
                        % 1=thresh, 2=max, 1.x is somewhere in between
                        for i=1:app.commandLevels
                            if templateRow(i)<1 % below threshold
                                pw(i)=0;
                            elseif templateRow(i)>=2 % at max
                                pw(i)=maxPW;
                            else
                                pw(i)=thPW+ramp*(templateRow(i)-1); %interpolate
                            end
                        end
                    
                    app.newPatterns{patRow}.pw=pw;
                    app.newPatterns{patRow}.amp=ones(1,app.commandLevels)*app.profileTable{profRow,app.colNum.Amp};

                    end
                end
            end
        if size(app.newPatterns,1)>0
            app.newPatterns=app.newPatterns'; % the array is indexed by column by default
            app.KeepButton.Enable=true;
        else
            app.KeepButton.Enable=false;
        end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
          %      PARENTAPP, templateStructure, profileTable, group, cmdID

            app.parentApp = varargin{1};
            app.templateStructure=varargin{2};
            app.profileTable=varargin{3};
            app.TemplateDropDown.Items=app.templateStructure.templateNames;            
            app.TemplateDropDown.ItemsData=[1:app.templateStructure.templateSize];            
            app.maxType=size(app.templateStructure.templateMuscleTypes.MuscleType,1);
            app.GroupEditField.Value=varargin{4};
            app.cmdIDEditField.Value=varargin{5};
            app.commandArray=app.templateStructure.templateCommandArray;
            app.commandLevels=size(app.commandArray,2);
            app.template(:,:)=app.templateStructure.templateMatrix(1,:,:); % default is first in dropdown

        end

        % Button pushed function: CreatePatternGroupButton
        function CreatePatternGroupButtonPushed(app, event)
            app.createPatterns();
        end

        % Value changed function: TemplateDropDown
        function TemplateDropDownValueChanged(app, event)
            value = app.TemplateDropDown.Value;
            app.template(:,:)=app.templateStructure.templateMatrix(value,:,:); 
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            app.parentApp.createdPatterns=app.newPatterns;
            app.parentApp.addCreatedPatterns=true;
            app.keepOrCancel=true;
             app.UIFigureCloseRequest();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.parentApp.createdPatterns=[];
            app.parentApp.addCreatedPatterns=false;
            app.keepOrCancel=true;
             app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.keepOrCancel
                %if not selected, then treat like cancel
                app.parentApp.createdPatterns=[];
                app.parentApp.addCreatedPatterns=false;
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GroupEditFieldLabel = uilabel(app.UIFigure);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.UIFigure, 'numeric');
            app.cmdIDEditFieldLabel = uilabel(app.UIFigure);
            app.cmdIDEditFieldLabel.Text = 'cmdID';
            app.cmdIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.cmdIDEditField.Limits = [1 8];
            app.cmdIDEditField.Value = 1;
            app.TemplateDropDownLabel = uilabel(app.UIFigure);
            app.TemplateDropDownLabel.Text = 'Template';
            app.TemplateDropDown = uidropdown(app.UIFigure);
            app.TemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateDropDownValueChanged, true);
            app.CreatePatternGroupButton = uibutton(app.UIFigure, 'push');
            app.CreatePatternGroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePatternGroupButtonPushed, true);
            app.CreatePatternGroupButton.Text = 'Create Pattern Group';
            app.KeepButton = uibutton(app.UIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Enable = 'off';
            app.KeepButton.Text = 'Keep';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternAutoCreate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end