classdef patternedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FIleMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        UIAxes                        matlab.ui.control.UIAxes
        EditModeSwitchLabel           matlab.ui.control.Label
        EditModeSwitch                matlab.ui.control.Switch
        ShowToolTipsCheckBox          matlab.ui.control.CheckBox
        ViewModeSwitch                matlab.ui.control.Switch
        StimulationPanel              matlab.ui.container.Panel
        StimONButton                  matlab.ui.control.Button
        StimOFFButton                 matlab.ui.control.Button
        StimModeSwitchLabel           matlab.ui.control.Label
        StimModeSwitch                matlab.ui.control.Switch
        HighBandwidthUsePDOsCheckBox  matlab.ui.control.CheckBox
        CommandValueEditFieldLabel    matlab.ui.control.Label
        CommandValueEditField         matlab.ui.control.NumericEditField
        PatternDropDownLabel          matlab.ui.control.Label
        PatternDropDown               matlab.ui.control.DropDown
        ModuleDropDownLabel           matlab.ui.control.Label
        ModuleDropDown                matlab.ui.control.DropDown
        SystemPatternsPanel           matlab.ui.container.Panel
        WritetoModulesButton          matlab.ui.control.Button
        ReadfromModulesButton         matlab.ui.control.Button
        CompareModulesButton          matlab.ui.control.Button
        CurrentModuleOnlyCheckBox     matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        PatternTable                  matlab.ui.control.Table
        ColorTable                    matlab.ui.control.Table
        PatternTableHeadings          matlab.ui.control.Table
        ButtonUp                      matlab.ui.control.Button
        ButtonDown                    matlab.ui.control.Button
        ButtonTop                     matlab.ui.control.Button
        ButtonBottom                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        PatternValuesTable            matlab.ui.control.Table
    end

    
    properties (Access = public)
        editMode = 'x'; % Description
        Plots = [];
        SmallPlots = [];
        PlotBars = [];
        PlotSlider = [];
        PlotSliderSmall = [];
        colorOrder = [ 0.6350    0.0780    0.1840
                       1.0000         0         0
                       0.8500    0.3250    0.0980
                       0.9290    0.6940    0.1250
                       0.4660    0.6740    0.1880
                       0.3010    0.7450    0.9330
                       0    0.4470    0.7410
                       0.4940    0.1840    0.5560];
       markerOrder = 'osv^ph';
       markerOrderLabel = '●■▼▲►◄'
       Patterns = [];
       ActivePatterns = [];
       UnselectedColor;
       activePatternRow;
       Y = [];
    end
    
    properties (Access = private)

    end


    methods (Access = public)
        
        function SliderClickCallback(app, src, evt )
            disp('Clicked Slider')
            hLine = src;
            ax = src.Parent;
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, ax, hLine, 'Slider'}; 
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback};
        end
        
        function LineClickCallback(app, src, evt )
            %LINECLICK Called on a click of a pattern or breakpoint line
            %
            % If 'HitTest' is set to 'on' clicking the line will trigger this callback
            % (not sure if threshold for click can be adjusted.  It doesn't seem
            % affected by line thickness)
            %  HitTest      pattern lines    breakpoint lines
            %  y-edit mode  ON               OFF
            %  x-edit mode  OFF              ON
            %
            % src is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            % evt is used to find the clicked point
            %
            %  The following inputs are only required for x-edit mode since all lines
            %  need to be updated when changing x-value, but are also passed during
            %  y-edit mode for simplicity:
            % hPlots are handles to all the Patterns plotted
            % hPlotBars are handles to all the Breakpoint lines
            %
            % Note:
            % axis CurrentPoint reports non NaN values when outside of plot area
            % figure CurrentPoint reports values in figure pixels, not axis units
            % evt.IntersectionPoint reports NaN values when outside plot area
            
            
            breakPointThreshold = 4; %if this is too low, we may create a new
            %breakpoint at the same time that we remove one
            
            xc = evt.IntersectionPoint(1);
            yc = evt.IntersectionPoint(2);
            hLine = src;
            ax = src.Parent;
            
            % Callback entered on line click. Find closest point on the line
            switch app.editMode
                case 'y'
                    y=abs(hLine.YData-yc);
                    x=abs(hLine.XData-xc);
                    d=y.^2+x.^2;
                    index=find(d==min(d));
                    
                    
                    %update PatternValues Table and PatternTable Selction
                    if ax == app.UIAxes
                        row = find(cellfun(@(x) x==hLine, app.Plots));
                    elseif ax == app.UIAxes2
                        row = find(cellfun(@(x) x==hLine, app.SmallPlots));
                    end
                    if isempty(row)
                        warning('Could not determine active pattern')
                    else
                        disp(['click on pattern row:' num2str(row)])
                        app.updateActivePattern(row);
                    end


                case 'x'
                    switch evt.Button
                        case 1 %left click
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; 
                        case 3 %right click
                            
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; %find breakpoint that matches current line handle
                            if isempty(index) %line handle must be a pattern line
                                xc = round(xc);
                                if any(abs(app.Plots{1}.XData-xc)<breakPointThreshold)
                                    disp('Breakpoint already exists at this value')
                                    return;
                                end
                                %insert the new breakpoint
                                before = app.Plots{1}.XData<xc;
                                after  = app.Plots{1}.XData>xc;
                                xbefore = app.Plots{1}.XData(before);
                                xafter = app.Plots{1}.XData(after);
                                for i=1:length(app.Plots)
                                    ybefore = app.Plots{i}.YData(before);
                                    yafter = app.Plots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.Plots{i}.XData = [xbefore, xc, xafter];
                                    app.Plots{i}.YData = [ybefore, yc, yafter];
                                end
                                for i=1:length(app.SmallPlots)
                                    ybefore = app.SmallPlots{i}.YData(before);
                                    yafter = app.SmallPlots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.SmallPlots{i}.XData = [xbefore, xc, xafter];
                                    app.SmallPlots{i}.YData = [ybefore, yc, yafter];
                                end
                                index = length(xbefore);
                                for i=length(app.PlotBars):-1:index+1
                                    app.PlotBars{i}.XData = app.PlotBars{i-1}.XData;
                                end
                                app.PlotBars{index}.XData = [xc xc];
                            else
                                resp = questdlg('Delete Breakpoint?');
                                if strcmp(resp, 'Yes')
                                    for i=1:length(app.Plots)
                                        app.Plots{i}.XData = [app.Plots{i}.XData(1:index-1) app.Plots{i}.XData(index+1:end)];
                                        app.Plots{i}.YData = [app.Plots{i}.YData(1:index-1) app.Plots{i}.YData(index+1:end)];
                                    end
                                    for i=1:length(app.SmallPlots)
                                        app.SmallPlots{i}.XData = [app.SmallPlots{i}.XData(1:index-1) app.SmallPlots{i}.XData(index+1:end)];
                                        app.SmallPlots{i}.YData = [app.SmallPlots{i}.YData(1:index-1) app.SmallPlots{i}.YData(index+1:end)];
                                    end
                                    for i=index-1:length(app.PlotBars)-2
                                        app.PlotBars{i}.XData = app.PlotBars{i+1}.XData;
                                    end
                                end
                            end
                            return;%don't start the Move CallBack
                    end
            end
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, ax, hLine, index}; 
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback, ax, hLine, index};
        end

        function MovePointCallback(app, src, evt, ax, line, i)
            %MOVEPOINTCALLBACK Summary of this function goes here
            %   Detailed explanation goes here
            
            %src is Figure Handle
            %evt is currently unused
            %line is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback
            %
            %Note:
            %axis CurrentPoint reports non NaN values when outside of plot area
            %figure CurrentPoint reports values in figure pixels, not axis units
            %evt.IntersectionPoint reports NaN values when outside plot area


            if isequal(i, 'Slider')
                xc = round(ax.CurrentPoint(1,1));
                xc = max(xc, 0);
                xc = min(xc, 255);
                line.XData = [xc xc];
                app.PlotSliderSmall.XData = [xc xc];
                app.CommandValueEditField.Value = xc;
                return;
            elseif length(i)~=1
                return
            end
            
            switch app.editMode
                case 'y' %line selected is a pattern line
                   % if toc > 0.1
                        
                        yc = round(ax.CurrentPoint(1,2));
                        %limit yc between 0 and ax.YLim(2)
                        yc = max(yc, 0);
                        yc = min(yc, ax.YLim(2));

                        
                        app.Y = [app.Y  yc];
                        %change YData for single Line
                        line.YData(i) = yc;
                        disp(['move point value' num2str(yc)])
                        
                   % end
                   % tic
                    
                    drawnow %limitrate nocallbacks
                    
                    %update PatternValuesTable in ReleaseClick


                case 'x' %line selected is a breakpoint line
                    %disp('move breakpoint')
                    xc = round(ax.CurrentPoint(1,1));
                    if i > 1 && i < length(app.Plots{1}.XData)
                        %limit xc between neighboring breakpoint values
                        xc = max(xc, app.Plots{1}.XData(i-1)+1);
                        xc = min(xc, app.Plots{1}.XData(i+1)-1);
                        %change XData for all patterns
                        for j=1:length(app.Plots)
                            app.Plots{j}.XData(i) = xc;
                        end
                        for j=1:length(app.SmallPlots)
                            app.SmallPlots{j}.XData(i) = xc;
                        end
                        %change XData for breakpoint line
                        line.XData = [xc xc];
                    end
            end
        end
    
        function ReleaseClickCallback(app, src, evt, ax, line, i )
            %RELEASECLICKCALLBACK called when mouse button is released
            % responsible for disabling the mouse movment callback, so it only occurs
            % while mouse button is pressed
            %
            % src is figure handle
            % evt is currently unused
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback (itself)
            src.WindowButtonMotionFcn = [];
            src.WindowButtonUpFcn = [];
   
            disp('Release Click')
            yc = line.YData(i);
            if ax==app.UIAxes
                app.PatternValuesTable.Data(i,2) = yc;
            elseif ax == app.UIAxes2
                app.PatternValuesTable.Data(i,3) = yc;
            end
            %JML TODO: Need to update stored pattern values
            
        end
        
    end
    
    methods (Access = private)
        
        function initAxes(app, ax, breakpoints, y, maxY, main)
            nMaxBreakPoints=20;
            nPatterns = size(y,1);    
            
            ax.Visible = 'off';
            
            cla(ax);
            hold(ax,'on');
            
             
            if main
                app.Plots = cell(nPatterns,1);
                for i=1:nPatterns
                    app.Plots{i} = plot(ax, breakpoints, y(i,:));
                end
                
            
                %we will only change XData and YData in the callback functions so we need
                %to have all the handles to possible plots already assigned.  Since
                %breakpoints can be added and removed, we will already allocate all 20
                %possible breakpoints, and just use NaNs as x values to make them
                %invisible.  We won't draw vertical bars for the first and last breakpoints
                app.PlotBars = cell(nMaxBreakPoints-2,1);
                for i=2:nMaxBreakPoints-1
                    if i<length(breakpoints)
                        x = [breakpoints(i) breakpoints(i)];
                    else
                        x = [NaN NaN];
                    end
                    app.PlotBars{i-1} = plot(ax, x, [0 maxY]);
                end
                app.PlotSlider = plot(ax, [20 20], [0 maxY], 'LineWidth', 4, 'Color', [1, 0.5, 0]);
            else
                app.SmallPlots = cell(nPatterns,1);
                for i=1:nPatterns
                    app.SmallPlots{i} = plot(ax, breakpoints, y(i,:));
                end
                app.PlotSliderSmall = plot(ax, [20 20], [0 maxY], 'LineWidth', 4, 'Color', [1, 0.5, 0]);
            end
            hold(ax,'off');
            
            axis(ax, [0 255 0 maxY]);
            %xlabel(ax, ['Command (' num2str(cmdID) ')']);
            if maxY == 255
                ylabel(ax, 'Pulse Width (us)')
            else
                ylabel(ax, 'Amplitude (mA)')
            end
            
            %title(ax, ['Group' num2str(group)])
            ax.XMinorTick = 'on';
            ax.YMinorTick = 'on';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.XMinorGrid = 'on';
            ax.YMinorGrid = 'on';
            ax.Toolbar.Visible = 'off';  %make the little toolbar at top right disappear.
            ax.Interactions = [dataTipInteraction];
            
            if main
                for i=1:length(app.Plots)
                    m = floor((i-1)/8)+1; %marker index
                    c = mod((i-1),8)+1;      %color index
                    app.Plots{i}.ButtonDownFcn = {@app.LineClickCallback};
                    app.Plots{i}.Marker = app.markerOrder(m);
                    app.Plots{i}.Color = app.colorOrder(c,:);
                    app.Plots{i}.MarkerFaceColor = app.Plots{i}.Color;
                    app.Plots{i}.MarkerEdgeColor = 'none';%app.Plots{i}.Color;
                    app.Plots{i}.LineWidth = 2;
                    %app.Plots{i}.HitTest = 'on';
                end
                
                    for i=1:length(app.PlotBars)
                        app.PlotBars{i}.ButtonDownFcn = {@app.LineClickCallback};
                        app.PlotBars{i}.LineWidth = 2;
                        app.PlotBars{i}.Color = 'k';
                        %app.PlotBars{i}.LineStyle = ':';
                        %app.PlotBars{i}.HitTest = 'on';
                    end
                    app.PlotSlider.ButtonDownFcn = {@app.SliderClickCallback};
            else
                for i=1:length(app.SmallPlots)

                    app.SmallPlots{i}.ButtonDownFcn = {@app.LineClickCallback};
                    app.SmallPlots{i}.Marker = app.Plots{i}.Marker;
                    app.SmallPlots{i}.Color = app.Plots{i}.Color;
                    app.SmallPlots{i}.MarkerFaceColor = app.SmallPlots{i}.Color;
                    app.SmallPlots{i}.MarkerEdgeColor = 'none';
                    app.SmallPlots{i}.LineWidth = 2;
                end
            end
            
            EditModeSwitchValueChanged(app, 0);
            ShowToolTipsCheckBoxValueChanged(app, 0);
            ax.Visible = 'on';
        
            
        end
        
        function setActivePatterns(app, p)
            app.ActivePatterns = app.Patterns(p);
            
            n = size(app.ActivePatterns,1); %number of active patterns
            d = cell(n,11);
            for i=1:n
                d{i,1} = app.ActivePatterns{i}.group;
                d{i,2} = app.ActivePatterns{i}.node;
                d{i,3} = app.ActivePatterns{i}.ch;
                d{i,4} = app.ActivePatterns{i}.cmdID;
                d{i,7} = false; %TODO change
                d{i,8} = true; %TODO change
            end

            app.PatternTable.Data = d;
            
            
            d = cell(n,1);
            for i=1:n
                m = floor((i-1)/8)+1;
                d{i} = app.markerOrderLabel(m);
            end
            app.ColorTable.Data = d;
            app.ColorTable.BackgroundColor = app.colorOrder;
            
            cmd = cellfun(@(x)x.cmd,app.ActivePatterns, 'UniformOutput', false);
            pw = cellfun(@(x)x.pw,app.ActivePatterns, 'UniformOutput', false);
            amp = cellfun(@(x)x.amp,app.ActivePatterns, 'UniformOutput', false);
            
            breakpoints = unique(cell2mat(cmd), 'rows');
            
            if size(breakpoints, 1) > 1
                warning('all plotted patterns must have same breakpoints')
                return;
            end
            %initAxes(app, ax, breakpoints, y, maxY, main)
            app.initAxes(app.UIAxes, breakpoints, cell2mat(pw), 255, true);
            app.initAxes(app.UIAxes2, breakpoints, cell2mat(amp), 200, false);
        end
        
        function CellSelectionCallback(app, src, evt)
            row = evt.Indices(1);
            app.updateActivePattern(row);
        end
        
        function PatternValueChanged(app, src, evt)
            disp('PatternValueChange');
            row = evt.Indices(1);
            col = evt.Indices(2);
            if col == 2 %PW
                if evt.NewData <= 255 && evt.NewData >= 0
                    app.Plots{app.activePatternRow}.YData(row) = floor(evt.NewData); %assumes large plot is PW
                else
                    app.PatternValuesTable.Data(row, col) = evt.PreviousData;
                end
            elseif col == 3 %Amp
                if evt.NewData <= 255 && evt.NewData >= 0
                    app.SmallPlots{app.activePatternRow}.YData(row) = floor(evt.NewData); %assumes small plot is Amp
                else
                    app.PatternValuesTable.Data(row, col) = evt.PreviousData;
                end
            end
            
        end
        
        function updateActivePattern(app, row)
            disp('UpdateActivePattern')
            %highlight active pattern in PatternTable
            app.activePatternRow = row;
            bg = app.UnselectedColor;
            bg(row,:) = [1 1 0]; %yellow
            app.PatternTable.BackgroundColor = bg;
            
            app.PatternValuesTable.Data = [app.ActivePatterns{row}.cmd 
                                           app.ActivePatterns{row}.pw 
                                           app.ActivePatterns{row}.amp]';

            %set all linewidths to normal                           
            for i=1:size(app.Plots, 1)
                app.Plots{i}.LineWidth = 2;
                app.Plots{i}.MarkerSize = 6;
                app.SmallPlots{i}.LineWidth = 2;
                app.SmallPlots{i}.MarkerSize = 6;
            end
            %set selected pattern linewidth/marker size
            app.Plots{row}.LineWidth = 4;
            app.Plots{row}.MarkerSize = 12;
            app.SmallPlots{row}.LineWidth = 4;
            app.SmallPlots{row}.MarkerSize = 12;
            
            %move active plot above others so easier to click
            moveToTop(app, app.UIAxes, app.Plots{row});
            moveToTop(app, app.UIAxes2, app.SmallPlots{row});
        end
        

        function moveToTop(app, axes, line)
        %move_to_bottom Moves the desired object to the bottom of the stack.
        %   For use with appdesigner objects where uistack no longer works.
       
           
            all_handles = axes.Children;
            
            if (numel(all_handles) < 2)
                return
            end
            
            all_indices = (1:numel(all_handles))';
            
            for index = 1:numel(line)
                if ishandle(line(index))
                    this_index = find(all_handles == line(index));
        
                    if ~isempty(this_index)
                        rest_of_indices = setdiff(all_indices, this_index);
                        all_handles = all_handles([this_index; rest_of_indices]);
                    end
                end
            end
            
            axes.Children = all_handles;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             breakpoints = [0 100 200 255];
%             
%             y = [100 20 40  50
%                 255 120 40 100
%                 30  40 50 70 ];
% 
%             cmdID = 1;
%             group =2;
%             
%             
%             
%             app.initAxes(app.UIAxes, breakpoints, y, 255, true);
%             app.initAxes(app.UIAxes2, breakpoints, y/2, 200, false);
            
            app.PatternTable.CellSelectionCallback={@app.CellSelectionCallback};
            stripe = [ 1.0000    1.0000    1.0000
                       0.9400    0.9400    0.9400];
            app.PatternTable.BackgroundColor = repmat(stripe, 24, 1); %support up to 48 rows in table
            app.UnselectedColor = app.PatternTable.BackgroundColor;
            
            app.PatternValuesTable.CellEditCallback = {@app.PatternValueChanged};
            app.UIFigure.GraphicsSmoothing = 'on';
        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
            if isequal(app.EditModeSwitch.Value, 'Breakpoints')
                disp('Edit X')
                app.editMode = 'x';
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = ':';
                    app.PlotBars{i}.HitTest = 'on'; 
                end
                for i=1:length(app.Plots)
                    app.Plots{i}.HitTest = 'on';
                end
                for i=1:length(app.SmallPlots)
                    app.SmallPlots{i}.HitTest = 'off';
                end
            else
                disp('Edit Y')
                app.editMode = 'y';
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = 'none';
                    app.PlotBars{i}.HitTest = 'off';
                end
                for i=1:length(app.Plots)
                    app.Plots{i}.HitTest = 'on';
                end
                for i=1:length(app.SmallPlots)
                    app.SmallPlots{i}.HitTest = 'on';
                end
            end
        end

        % Value changed function: ShowToolTipsCheckBox
        function ShowToolTipsCheckBoxValueChanged(app, event)
            if app.ShowToolTipsCheckBox.Value
                enableDefaultInteractivity(app.UIAxes);
                enableDefaultInteractivity(app.UIAxes2);
            else
                disableDefaultInteractivity(app.UIAxes);
                disableDefaultInteractivity(app.UIAxes2);
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, filepath] = uigetfile('.mat', 'Select Patterns File');
            if filename
                ld = load([filepath filename]);
            end
            
            p = ld.Patterns; %JML TODO: 
            n = size(p,1); %number of patterns
            
            nodes = unique(cellfun(@(x) x.node, p));
            groups = unique(cellfun(@(x) x.group, p));
            app.ModuleDropDown.Items = [{'-'}; arrayfun(@num2str, nodes, 'UniformOutput',false)];
            app.PatternDropDown.Items = [{'-'}; arrayfun(@num2str, groups, 'UniformOutput',false)];
            app.Patterns = p;
           
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            app.PatternTable.Position(2) = max(app.PatternTable.Position(2)-100, -636);
            app.ColorTable.Position(2) = app.PatternTable.Position(2)
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
            app.PatternTable.Position(2) = min(app.PatternTable.Position(2)+100, 0);
            app.ColorTable.Position(2) = app.PatternTable.Position(2)
        end

        % Button pushed function: ButtonTop
        function ButtonTopPushed(app, event)
            app.PatternTable.Position(2) = -636;
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonBottom
        function ButtonBottomPushed(app, event)
            app.PatternTable.Position(2) = 0;
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Value changed function: PatternDropDown
        function PatternDropDownValueChanged(app, event)
            currentGroup = str2double(app.PatternDropDown.Value); 
            p = cellfun(@(x) x.group == currentGroup, app.Patterns);
            app.setActivePatterns(p);
        end

        % Value changed function: ModuleDropDown
        function ModuleDropDownValueChanged(app, event)
            currentNode = str2double(app.ModuleDropDown.Value); 
            p = cellfun(@(x) x.node == currentNode, app.Patterns);
            app.setActivePatterns(p);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            switch event.Key
                case 'leftarrow'
                    %jump slider to previous breakpoint
                    cmd = app.CommandValueEditField.Value;
                    i = find(app.Plots{1}.XData<cmd, 1, 'last');
                    
                    if ~isempty(i)
                        x = app.Plots{1}.XData(i);
                        app.PlotSlider.XData = [x x];
                        app.PlotSliderSmall.XData = [x x];
                        app.CommandValueEditField.Value = x;
                    end
                                
                case 'rightarrow'
                    %jump slider to next breakpoint
                    cmd = app.CommandValueEditField.Value;
                    i = find(app.Plots{1}.XData>cmd, 1, 'first');
                    
                    if ~isempty(i)
                        x = app.Plots{1}.XData(i);
                        app.PlotSlider.XData = [x x];
                        app.PlotSliderSmall.XData = [x x];
                        app.CommandValueEditField.Value = x;
                    end
            end
        end

        % Value changed function: CommandValueEditField
        function CommandValueEditFieldValueChanged(app, event)
            x = app.CommandValueEditField.Value;
            app.PlotSlider.XData = [x x];
            app.PlotSliderSmall.XData = [x x];     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pattern Editor';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FIleMenu = uimenu(app.UIFigure);
            app.FIleMenu.Text = 'FIle';
            app.OpenMenu = uimenu(app.FIleMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FIleMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FIleMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.EditModeSwitchLabel = uilabel(app.UIFigure);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditModeSwitch.Items = {'Pattern Values', 'Breakpoints'};
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.EditModeSwitch.Value = 'Pattern Values';
            app.ShowToolTipsCheckBox = uicheckbox(app.UIFigure);
            app.ShowToolTipsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowToolTipsCheckBoxValueChanged, true);
            app.ShowToolTipsCheckBox.Text = 'Show ToolTips';
            app.ViewModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ViewModeSwitch.Items = {'Amplitude', 'Pulse Width'};
            app.ViewModeSwitch.Value = 'Pulse Width';
            app.StimulationPanel = uipanel(app.UIFigure);
            app.StimulationPanel.Title = 'Stimulation';
            app.StimONButton = uibutton(app.StimulationPanel, 'push');
            app.StimONButton.Text = 'Stim ON';
            app.StimOFFButton = uibutton(app.StimulationPanel, 'push');
            app.StimOFFButton.Text = 'Stim OFF';
            app.StimModeSwitchLabel = uilabel(app.StimulationPanel);
            app.StimModeSwitchLabel.Text = 'Stim Mode';
            app.StimModeSwitch = uiswitch(app.StimulationPanel, 'slider');
            app.StimModeSwitch.Items = {'Downloaded', 'Displayed'};
            app.StimModeSwitch.Value = 'Downloaded';
            app.HighBandwidthUsePDOsCheckBox = uicheckbox(app.StimulationPanel);
            app.HighBandwidthUsePDOsCheckBox.Text = {'High Bandwidth'; '(Use PDOs)'};
            app.CommandValueEditFieldLabel = uilabel(app.StimulationPanel);
            app.CommandValueEditFieldLabel.Text = 'Command Value';
            app.CommandValueEditField = uieditfield(app.StimulationPanel, 'numeric');
            app.CommandValueEditField.RoundFractionalValues = 'on';
            app.CommandValueEditField.ValueChangedFcn = createCallbackFcn(app, @CommandValueEditFieldValueChanged, true);
            app.PatternDropDownLabel = uilabel(app.UIFigure);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.UIFigure);
            app.PatternDropDown.Items = {'-'};
            app.PatternDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternDropDownValueChanged, true);
            app.PatternDropDown.Value = '-';
            app.ModuleDropDownLabel = uilabel(app.UIFigure);
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.UIFigure);
            app.ModuleDropDown.Items = {'-'};
            app.ModuleDropDown.ValueChangedFcn = createCallbackFcn(app, @ModuleDropDownValueChanged, true);
            app.ModuleDropDown.Value = '-';
            app.SystemPatternsPanel = uipanel(app.UIFigure);
            app.SystemPatternsPanel.Title = 'System Patterns';
            app.WritetoModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.WritetoModulesButton.Text = 'Write to Module(s)';
            app.ReadfromModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.ReadfromModulesButton.Text = 'Read from Module(s)';
            app.CompareModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.CompareModulesButton.Text = 'Compare Module(s)';
            app.CurrentModuleOnlyCheckBox = uicheckbox(app.SystemPatternsPanel);
            app.CurrentModuleOnlyCheckBox.Text = 'Current Module Only';
            app.Panel = uipanel(app.UIFigure);
            app.PatternTable = uitable(app.Panel);
            app.PatternTable.ColumnName = {''};
            app.PatternTable.RowName = {};
            app.PatternTable.ColumnEditable = [false false false false false false true true false false false];
            app.ColorTable = uitable(app.Panel);
            app.ColorTable.ColumnName = {''};
            app.ColorTable.RowName = {};
            app.PatternTableHeadings = uitable(app.Panel);
            app.PatternTableHeadings.ColumnName = {'Pattern Group'; 'Node'; 'Ch'; 'Cmd'; 'Min'; 'Max'; 'Show'; 'Mute'; 'PW'; 'Amp'; 'Muscle'; 'Select'};
            app.PatternTableHeadings.RowName = {};
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Text = '▲';
            app.ButtonDown = uibutton(app.UIFigure, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Text = '▼';
            app.ButtonTop = uibutton(app.UIFigure, 'push');
            app.ButtonTop.ButtonPushedFcn = createCallbackFcn(app, @ButtonTopPushed, true);
            app.ButtonTop.Text = {'▬'; '▲'};
            app.ButtonBottom = uibutton(app.UIFigure, 'push');
            app.ButtonBottom.ButtonPushedFcn = createCallbackFcn(app, @ButtonBottomPushed, true);
            app.ButtonBottom.Text = {'▼'; '▬'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.Visible = 'off';
            app.PatternValuesTable = uitable(app.UIFigure);
            app.PatternValuesTable.ColumnName = {'cmd'; 'PW'; 'Amp'};
            app.PatternValuesTable.RowName = {''};
            app.PatternValuesTable.ColumnSortable = false;
            app.PatternValuesTable.ColumnEditable = [false true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternedit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end