classdef phoneAppFunctions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        phoneAppEmulation       matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        SaveAsMenu              matlab.ui.container.Menu
        PhoneAppFunctionsLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Messages                matlab.ui.control.TextArea
        ClearMessageButton      matlab.ui.control.Button
        MessagesLabel           matlab.ui.control.Label
        PMVersionButton         matlab.ui.control.Button
        NetOnButton             matlab.ui.control.Button
        NetOffButton            matlab.ui.control.Button
        HandPanel               matlab.ui.container.Panel
        HandButton1             matlab.ui.control.Button
        HandButton2             matlab.ui.control.Button
        HandButton3             matlab.ui.control.Button
        HandButton4             matlab.ui.control.Button
        HandButton5             matlab.ui.control.Button
        ExercisePanel           matlab.ui.container.Panel
        ExerciseButton1         matlab.ui.control.Button
        Exercise2Button2        matlab.ui.control.Button
        Exercise2Button3        matlab.ui.control.Button
        Exercise2Button4        matlab.ui.control.Button
        Exercise2Button5        matlab.ui.control.Button
        TrunkPanel              matlab.ui.container.Panel
        TrunkButton1            matlab.ui.control.Button
        TrunkButton2            matlab.ui.control.Button
        TrunkButton3            matlab.ui.control.Button
        TrunkButton4            matlab.ui.control.Button
        TrunkButton5            matlab.ui.control.Button
        StopPanel               matlab.ui.container.Panel
        StopHandButton          matlab.ui.control.Button
        StopBothButton          matlab.ui.control.Button
        StopTrunkButton         matlab.ui.control.Button
        ScriptsPanel            matlab.ui.container.Panel
        EnableScriptsButton     matlab.ui.control.Button
        DisableScriptsButton    matlab.ui.control.Button
        StartupScriptButton     matlab.ui.control.Button
        CoughPanel              matlab.ui.container.Panel
        EnableCoughButton       matlab.ui.control.Button
        CoughButton             matlab.ui.control.Button
        StatusPanel             matlab.ui.container.Panel
        GetStatusButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        nnp=[]; % nnpapi
        A57=zeros(150,1); % PM 1F57 array
        filename57=[];
        pathname=[];
    end
    
    methods (Access = public)
        
        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function success = startHand(app,pat)
            % if either message fails, then consider both failed
            resp=app.nnp.write(7,'1F53',22,uint8(pat),'uint8'); % immediate pattern set  
            success=(resp==0);
            if resp==0
                resp2=app.nnp.write(7,'1F53',4,uint8(5),'uint8');
                if resp2==0
                    message=['Start ADL',num2str(pat)];
                else
                    message=['Start ADL',num2str(pat),' failed'];
                end
            else
                message=['Start ADL',num2str(pat),' failed'];
            end
            app.TopMessage(message);         
        end
        
        function success = stopHand(app)
            resp=app.nnp.write(7,'1F53',4,uint8(8),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop ADL';
            else
                message=['Stop ADL',' failed'];
            end
            app.TopMessage(message);
        end
        
        function success = startTrunk(app,pat)
            resp=app.nnp.write(7,'1F57',99,uint8(pat),'uint8'); % immediate pattern set  
            success=(resp==0);
            if resp==0
                resp2=app.nnp.write(7,'1F53',4,uint8(1),'uint8');
                if resp2==0
                    message=['Start Trunk ',num2str(pat)];
                else
                    message=['Start ADL',num2str(pat),' failed'];
                end
            else
                message=['Start ADL',num2str(pat),' failed'];
            end
            app.TopMessage(message);          
        end
      
        function success = stopTrunk(app)
            resp=app.nnp.write(7,'1F53',4,uint8(7),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop Trunk';
            else
                message=['Stop Trunk',' failed'];
            end
            app.TopMessage(message);    
        end
      
        function success =  startExercise(app,pat)
%             Ext =  action
%             1 = 6
%             2 = 2
%             3 = 3
%             4 = 4
%             5 = used by standing exercise via transfer assist button in phone
            if pat>0 && pat<6
                if pat==1
                    act=6;
                else
                    act=pat;
                end
                resp2=app.nnp.write(7,'1F53',4,uint8(act),'uint8');
                if resp2==0
                    message=['Exercise ',num2str(pat)];
                else
                    message=['Exercise',num2str(pat),' failed'];
                end
            else
                message=['Exercise',' failed'];
            end
            app.TopMessage(message);
        end
        
        function success = stopAll(app)
            resp=app.nnp.write(7,'1F53',4,uint8(9),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop All';
            else
                message=['Stop All',' failed'];
            end
            app.TopMessage(message);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            % read in variables

        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp)
                dmessage=sprintf('PM version %d',resp);
            else
                dmessage='PM not read';
            end
            app.TopMessage(dmessage);
            
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Button pushed function: NetOnButton
        function NetOnButtonPushed(app, event)
            if ~app.nnp.networkOn() % will put into waiting, returns true if successful
                app.TopMessage('Network On failed');
                return;
            else
                app.TopMessage('Network On');
            end
        end

        % Button pushed function: NetOffButton
        function NetOffButtonPushed(app, event)
            if ~app.nnp.networkOff() % will put into waiting, returns true if successful
                app.TopMessage('Network Off failed');
                return;
            else
                app.TopMessage('Network Off');
            end
        end

        % Button pushed function: HandButton1
        function HandButton1Pushed(app, event)
            app.startHand(1);
        end

        % Button pushed function: HandButton2
        function HandButton2Pushed(app, event)
            app.startHand(2);
        end

        % Button pushed function: HandButton3
        function HandButton3Pushed(app, event)
            app.startHand(3);
        end

        % Button pushed function: HandButton4
        function HandButton4Pushed(app, event)
            app.startHand(4);
        end

        % Button pushed function: HandButton5
        function HandButton5Pushed(app, event)
            app.startHand(5);
        end

        % Button pushed function: ExerciseButton1
        function ExerciseButton1Pushed(app, event)
            app.startExercise(1);
        end

        % Button pushed function: Exercise2Button2
        function Exercise2Button2Pushed(app, event)
            app.startExercise(2);
        end

        % Button pushed function: Exercise2Button3
        function Exercise2Button3Pushed(app, event)
            app.startExercise(3);
        end

        % Button pushed function: Exercise2Button4
        function Exercise2Button4Pushed(app, event)
            app.startExercise(4);
        end

        % Button pushed function: Exercise2Button5
        function Exercise2Button5Pushed(app, event)
            app.startExercise(5);
        end

        % Button pushed function: TrunkButton1
        function TrunkButton1Pushed(app, event)
            app.startTrunk(1);
        end

        % Button pushed function: TrunkButton2
        function TrunkButton2Pushed(app, event)
            app.startTrunk(2);
        end

        % Button pushed function: TrunkButton3
        function TrunkButton3Pushed(app, event)
            app.startTrunk(3);
        end

        % Button pushed function: TrunkButton4
        function TrunkButton4Pushed(app, event)
            app.startTrunk(4);
        end

        % Button pushed function: TrunkButton5
        function TrunkButton5Pushed(app, event)
            app.startTrunk(5);
        end

        % Button pushed function: StopHandButton
        function StopHandButtonPushed(app, event)
            app.stopHand();
        end

        % Button pushed function: StopBothButton
        function StopBothButtonPushed(app, event)
            app.stopAll();
        end

        % Button pushed function: StopTrunkButton
        function StopTrunkButtonPushed(app, event)
            app.stopTrunk();
        end

        % Button pushed function: EnableScriptsButton
        function EnableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                message='Scripts Enabled';
            else
                message=app.nnp.lastError;
            end
            app.TopMessage(message);
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            if response==hex2dec('8F')
                message='Scripts Disabled';
            else
                message=app.nnp.lastError;
            end
            app.TopMessage(message);
        end

        % Button pushed function: StartupScriptButton
        function StartupScriptButtonPushed(app, event)
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                message='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    message='Scripts enabled and Startup Ran';
                    
                else
                    message=['Scripts Enabled, but ',app.nnp.lastError];
                    
                end
            else
                message=app.nnp.lastError;
            end
            app.TopMessage(message);
        end

        % Button pushed function: EnableCoughButton
        function EnableCoughButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(27),'uint8');
                if resp2==0
                    message='Enable Cough';
                else
                    message=['Enable Cough',' failed'];
                end 
                  app.TopMessage(message);
        end

        % Button pushed function: CoughButton
        function CoughButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',113,uint8(1),'uint8');
                if resp2==0
                    message='Cough';
                else
                    message=['Cough',' failed'];
                end 
                  app.TopMessage(message);
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
                     [modes, temp, vsys, net, rssi] = app.nnp.getStatus();
            if ~isempty(modes)
                if net
                    str = sprintf('Network is ON');
                else
                    str = sprintf('Network is OFF');
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];
                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end
                
                h = msgbox(str, 'Get Status', 'modal');
                uiwait(h);
            else
                h = msgbox('Could not get Status', 'Get Status Error', 'error', 'modal');
                uiwait(h);
            end
                  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.phoneAppEmulation = uifigure('Visible', 'off');
            app.phoneAppEmulation.Name = 'Phone App Functions';
            app.FileMenu = uimenu(app.phoneAppEmulation);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'SaveAs';
            app.PhoneAppFunctionsLabel = uilabel(app.phoneAppEmulation);
            app.PhoneAppFunctionsLabel.Text = 'Phone App Functions';
            app.Panel = uipanel(app.phoneAppEmulation);
            app.Panel.Title = 'Panel';
            app.Messages = uitextarea(app.Panel);
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.MessagesLabel = uilabel(app.Panel);
            app.MessagesLabel.Text = 'Messages';
            app.PMVersionButton = uibutton(app.Panel, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.NetOnButton = uibutton(app.Panel, 'push');
            app.NetOnButton.ButtonPushedFcn = createCallbackFcn(app, @NetOnButtonPushed, true);
            app.NetOnButton.Text = 'Net On';
            app.NetOffButton = uibutton(app.Panel, 'push');
            app.NetOffButton.ButtonPushedFcn = createCallbackFcn(app, @NetOffButtonPushed, true);
            app.NetOffButton.Text = 'Net Off';
            app.HandPanel = uipanel(app.phoneAppEmulation);
            app.HandPanel.Title = 'Hand';
            app.HandButton1 = uibutton(app.HandPanel, 'push');
            app.HandButton1.ButtonPushedFcn = createCallbackFcn(app, @HandButton1Pushed, true);
            app.HandButton1.Text = 'Hand 1';
            app.HandButton2 = uibutton(app.HandPanel, 'push');
            app.HandButton2.ButtonPushedFcn = createCallbackFcn(app, @HandButton2Pushed, true);
            app.HandButton2.Text = 'Hand 2';
            app.HandButton3 = uibutton(app.HandPanel, 'push');
            app.HandButton3.ButtonPushedFcn = createCallbackFcn(app, @HandButton3Pushed, true);
            app.HandButton3.Text = 'Hand 3';
            app.HandButton4 = uibutton(app.HandPanel, 'push');
            app.HandButton4.ButtonPushedFcn = createCallbackFcn(app, @HandButton4Pushed, true);
            app.HandButton4.Text = 'Hand 4';
            app.HandButton5 = uibutton(app.HandPanel, 'push');
            app.HandButton5.ButtonPushedFcn = createCallbackFcn(app, @HandButton5Pushed, true);
            app.HandButton5.Text = 'Hand 5';
            app.ExercisePanel = uipanel(app.phoneAppEmulation);
            app.ExercisePanel.Title = 'Exercise';
            app.ExerciseButton1 = uibutton(app.ExercisePanel, 'push');
            app.ExerciseButton1.ButtonPushedFcn = createCallbackFcn(app, @ExerciseButton1Pushed, true);
            app.ExerciseButton1.Text = 'Exercise 1';
            app.Exercise2Button2 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button2.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button2Pushed, true);
            app.Exercise2Button2.Text = 'Exercise 2';
            app.Exercise2Button3 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button3.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button3Pushed, true);
            app.Exercise2Button3.Text = 'Exercise 3';
            app.Exercise2Button4 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button4.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button4Pushed, true);
            app.Exercise2Button4.Text = 'Exercise 4';
            app.Exercise2Button5 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button5.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button5Pushed, true);
            app.Exercise2Button5.Text = 'Exercise 5';
            app.TrunkPanel = uipanel(app.phoneAppEmulation);
            app.TrunkPanel.Title = 'Trunk';
            app.TrunkButton1 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton1.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton1Pushed, true);
            app.TrunkButton1.Text = 'Trunk 1';
            app.TrunkButton2 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton2.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton2Pushed, true);
            app.TrunkButton2.Text = 'Trunk 2';
            app.TrunkButton3 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton3.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton3Pushed, true);
            app.TrunkButton3.Text = 'Trunk 3';
            app.TrunkButton4 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton4.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton4Pushed, true);
            app.TrunkButton4.Text = 'Trunk 4';
            app.TrunkButton5 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton5.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton5Pushed, true);
            app.TrunkButton5.Text = 'Trunk 5';
            app.StopPanel = uipanel(app.phoneAppEmulation);
            app.StopPanel.Title = 'Stop';
            app.StopHandButton = uibutton(app.StopPanel, 'push');
            app.StopHandButton.ButtonPushedFcn = createCallbackFcn(app, @StopHandButtonPushed, true);
            app.StopHandButton.Text = {'Stop'; 'Hand'};
            app.StopBothButton = uibutton(app.StopPanel, 'push');
            app.StopBothButton.ButtonPushedFcn = createCallbackFcn(app, @StopBothButtonPushed, true);
            app.StopBothButton.Text = {'Stop'; 'Both'};
            app.StopTrunkButton = uibutton(app.StopPanel, 'push');
            app.StopTrunkButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrunkButtonPushed, true);
            app.StopTrunkButton.Text = {'Stop'; 'Trunk'};
            app.ScriptsPanel = uipanel(app.phoneAppEmulation);
            app.ScriptsPanel.Title = 'Scripts';
            app.EnableScriptsButton = uibutton(app.ScriptsPanel, 'push');
            app.EnableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @EnableScriptsButtonPushed, true);
            app.EnableScriptsButton.Text = {'Enable'; 'Scripts'};
            app.DisableScriptsButton = uibutton(app.ScriptsPanel, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Text = {'Disable'; 'Scripts'};
            app.StartupScriptButton = uibutton(app.ScriptsPanel, 'push');
            app.StartupScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupScriptButton.Text = {'Startup'; 'Script'};
            app.CoughPanel = uipanel(app.phoneAppEmulation);
            app.CoughPanel.Title = 'Cough';
            app.EnableCoughButton = uibutton(app.CoughPanel, 'push');
            app.EnableCoughButton.ButtonPushedFcn = createCallbackFcn(app, @EnableCoughButtonPushed, true);
            app.EnableCoughButton.Text = {'Enable'; 'Cough'};
            app.CoughButton = uibutton(app.CoughPanel, 'push');
            app.CoughButton.ButtonPushedFcn = createCallbackFcn(app, @CoughButtonPushed, true);
            app.CoughButton.Text = 'Cough';
            app.StatusPanel = uipanel(app.phoneAppEmulation);
            app.StatusPanel.Title = 'Status';
            app.GetStatusButton = uibutton(app.StatusPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            % Show the figure after all components are created
            app.phoneAppEmulation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phoneAppFunctions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.phoneAppEmulation)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.phoneAppEmulation)
        end
    end
end