classdef rmParametersStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RemoteInfo         matlab.ui.Figure
        DisplayRemoteModuleInformationandStatusLabel  matlab.ui.control.Label
        ScanButton         matlab.ui.control.Button
        SavetoFileButton   matlab.ui.control.Button
        RemoteModInfo      matlab.ui.control.TextArea
        ScanningLabel      matlab.ui.control.Label
        StopNetworkButton  matlab.ui.control.Button
        Messages           matlab.ui.control.EditField
        NetworkLamp        matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        nnp = [];
        defnodes=[1:6,9]; % default nodes to scan
        RMinfoString=[]; % string to write to display
        callingFigure=[]; %handle to calling figure
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.ScanningLabel.Visible='off';
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            pause(.1);
            % get network status
            resp=app.nnp.getNetworkStatus();
            if length(resp)==1
                if resp==1
                    app.NetworkLamp.Color=[0,1,0];
                end
            else
                app.Messages.Value='Could not read network status';
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.Messages.Value='';
            app.ScanningLabel.Visible='on';
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    app.NetworkLamp.Color=[0,1,0];
                    pause(.5); %wait for network to start
                    app.RMinfoString=rmSettingsReadFcn(app.nnp,app.defnodes);
                    app.RemoteModInfo.Value=app.RMinfoString;
                else
                    app.Messages.Value='Enter Waiting failed';
                end
            else
                app.Messages.Value='Network On failed';
            end
            app.ScanningLabel.Visible='off';
        end

        % Button pushed function: StopNetworkButton
        function StopNetworkButtonPushed(app, event)
            app.Messages.Value='';
            if ~app.nnp.enterWaiting()
                app.Messages.Value='Enter Waiting failed';
                return;
            end
            if ~app.nnp.networkOff()
                app.Messages.Value='Network Off failed';
                return;
            else
                app.NetworkLamp.Color=[0.9,0.9,0.9];
            end
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Get file name and write string to file.         
            timestring=sprintf('_%02.0f',datevec(now));
            defaultname=['RMStatus',timestring,'.txt'];
            [fn,pn]=uiputfile('*.txt','Specify Filename and location for output text file',defaultname);
            savename=fullfile(pn,fn);
            SaveID=fopen(savename,'a'); %open in append mode in case I select the wrong file
            fprintf(SaveID,'%s',app.RMinfoString);
            fclose(SaveID);
        end

        % Close request function: RemoteInfo
        function RemoteInfoCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RemoteInfo = uifigure('Visible', 'off');
            app.RemoteInfo.Name = 'Remote Module Information';
            app.RemoteInfo.CloseRequestFcn = createCallbackFcn(app, @RemoteInfoCloseRequest, true);
            app.DisplayRemoteModuleInformationandStatusLabel = uilabel(app.RemoteInfo);
            app.DisplayRemoteModuleInformationandStatusLabel.Text = 'Display Remote Module Information and Status';
            app.ScanButton = uibutton(app.RemoteInfo, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.SavetoFileButton = uibutton(app.RemoteInfo, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = {'Save '; 'to File'};
            app.RemoteModInfo = uitextarea(app.RemoteInfo);
            app.ScanningLabel = uilabel(app.RemoteInfo);
            app.ScanningLabel.Text = 'Scanning ...';
            app.StopNetworkButton = uibutton(app.RemoteInfo, 'push');
            app.StopNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @StopNetworkButtonPushed, true);
            app.StopNetworkButton.Text = {'Stop'; 'Network'};
            app.Messages = uieditfield(app.RemoteInfo, 'text');
            app.NetworkLamp = uilamp(app.RemoteInfo);
            % Show the figure after all components are created
            app.RemoteInfo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rmParametersStatus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RemoteInfo)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RemoteInfo)
        end
    end
end